classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        IndicadoresPanel      matlab.ui.container.Panel
        OperacionLampLabel    matlab.ui.control.Label
        OperacionLamp         matlab.ui.control.Lamp
        PausaLampLabel        matlab.ui.control.Label
        PausaLamp             matlab.ui.control.Lamp
        ParadaLampLabel       matlab.ui.control.Label
        ParadaLamp            matlab.ui.control.Lamp
        CreaciondelmapaPanel  matlab.ui.container.Panel
        ResolucionLabel       matlab.ui.control.Label
        Resolucion            matlab.ui.control.NumericEditField
        CargarmapaButton      matlab.ui.control.Button
        Mapa                  matlab.ui.control.Lamp
        UIAxes                matlab.ui.control.UIAxes
        GeneracionderutaAlgoritmoPRMPanel  matlab.ui.container.Panel
        PuntoinicialPanel     matlab.ui.container.Panel
        xLabel                matlab.ui.control.Label
        xi                    matlab.ui.control.NumericEditField
        yLabel                matlab.ui.control.Label
        yi                    matlab.ui.control.NumericEditField
        PuntofinalPanel       matlab.ui.container.Panel
        xLabel_2              matlab.ui.control.Label
        xf                    matlab.ui.control.NumericEditField
        yLabel_2              matlab.ui.control.Label
        yf                    matlab.ui.control.NumericEditField
        NodosEditFieldLabel   matlab.ui.control.Label
        Nodos                 matlab.ui.control.NumericEditField
        GenerarRutaButton     matlab.ui.control.Button
        Ruta                  matlab.ui.control.Lamp
        ROSGazeboPanel        matlab.ui.container.Panel
        ConectaraROSSwitch    matlab.ui.control.Switch
        InicioButton          matlab.ui.control.Button
        ParadaButton          matlab.ui.control.Button
        ConectaraROSLabel     matlab.ui.control.Label
        RobotPanel            matlab.ui.container.Panel
        xLabel_3              matlab.ui.control.Label
        yLabel_3              matlab.ui.control.Label
        yawLabel              matlab.ui.control.Label
        x_robot               matlab.ui.control.Label
        y_robot               matlab.ui.control.Label
        yaw_robot             matlab.ui.control.Label
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Map
        path
        StopNow = false
    end
    
    methods (Access = private)
        
        function dibrob(app,xc,yc,r,theta)
            tc=0:pi/8:2*pi;
            x=[xc xc+r*cos(tc+theta)];
            y=[yc yc+r*sin(tc+theta)];
            plot(app.UIAxes_3,x,y)
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarRutaButton
        function GenerarRutaButtonPushed(app, event)
            app.Ruta.Color = [1, 1, 0];
            % PRM - Probabilistic Roadmap
            prm = mobileRobotPRM(app.Map,app.Nodos.Value);
            show(prm,'parent',app.UIAxes)
            
            % Punto inicial y final
            startLocation = [app.xi.Value app.yi.Value];
            endLocation = [app.xf.Value app.yf.Value];
            
            % Ruta encontrada
            app.path = findpath(prm,startLocation,endLocation);
            show(prm,'parent',app.UIAxes)
            app.Ruta.Color = [0, 1, 0];
        end

        % Button pushed function: CargarmapaButton
        function CargarmapaButtonPushed(app, event)
            app.Mapa.Color = [1, 1, 0];
            Resolution = app.Resolucion.Value;
            app.Map= binaryOccupancyMap(19,19,Resolution);% Creación del Mapa
            
            %% Relleno - Parte de arriba
            LHor1_Initx=0;
            LHor1_Inity=17;
            LHor1_Endx=14;
            LHor1_Points= (LHor1_Endx-LHor1_Initx)*Resolution;
            LHor1_x(1)=LHor1_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor1_y(1)=LHor1_Inity;
                for n=1:LHor1_Points
                    LHor1_x(n+1)=LHor1_x(n)+1/Resolution;
                    LHor1_y(n+1)=LHor1_y(n);
                    
                end
                setOccupancy(app.Map,[LHor1_x' LHor1_y'], ones(length(LHor1_x),1))
                LHor1_Inity=LHor1_Inity+1/Resolution;
            end
            
            %% Relleno - Parte de abajo
            LHor1_Initx=5;
            LHor1_Inity=1.2;
            LHor1_Endx=19;
            LHor1_Points= (LHor1_Endx-LHor1_Initx)*Resolution;
            LHor1_x(1)=LHor1_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor1_y(1)=LHor1_Inity;
                for n=1:LHor1_Points
                    LHor1_x(n+1)=LHor1_x(n)+1/Resolution;
                    LHor1_y(n+1)=LHor1_y(n);
                    
                end
                setOccupancy(app.Map,[LHor1_x' LHor1_y'], ones(length(LHor1_x),1))
                LHor1_Inity=LHor1_Inity+1/Resolution;
            end
            
            
            %% Creación Obstáculos Circulares
            x= [7;15];
            y= [6.5;11];
            
            setOccupancy(app.Map,[x y], ones(2,1))
            
            inflate(app.Map,0.75)
            
            x= [4.25];
            y= [12.5];
            
            setOccupancy(app.Map,[x y], ones(1,1))
            
            inflate(app.Map,0.75)
            
            %% Creación del marco
            % Lineas horizontales
            
            LHor1_Initx=0;
            LHor1_Inity=0;
            LHor1_Endx=4;
            LHor1_Points= (LHor1_Endx-LHor1_Initx)*Resolution;
            LHor1_x(1)=LHor1_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor1_y(1)=LHor1_Inity;
                for n=1:LHor1_Points
                    LHor1_x(n+1)=LHor1_x(n)+1/Resolution;
                    LHor1_y(n+1)=LHor1_y(n);
                    
                end
                setOccupancy(app.Map,[LHor1_x' LHor1_y'], ones(length(LHor1_x),1))
                LHor1_Inity=LHor1_Inity+1/Resolution;
            end
            
            
            LHor2_Initx=3.5;
            LHor2_Inity=3;
            LHor2_Endx=19;
            LHor2_Points= (LHor2_Endx-LHor2_Initx)*Resolution;
            LHor2_x(1)=LHor2_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor2_y(1)=LHor2_Inity;
                for n=1:LHor2_Points
                    LHor2_x(n+1)=LHor2_x(n)+1/Resolution;
                    LHor2_y(n+1)=LHor2_y(n);
                    
                end
                setOccupancy(app.Map,[LHor2_x' LHor2_y'], ones(length(LHor2_x),1))
                LHor2_Inity=LHor2_Inity+1/Resolution;
            end
            
            LHor3_Initx=0;
            LHor3_Inity=15.5;
            LHor3_Endx=19-3.5;
            LHor3_Points= (LHor3_Endx-LHor3_Initx)*Resolution;
            LHor3_x(1)=LHor3_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor3_y(1)=LHor3_Inity;
                for n=1:LHor3_Points
                    LHor3_x(n+1)=LHor3_x(n)+1/Resolution;
                    LHor3_y(n+1)=LHor3_y(n);
                    
                end
                setOccupancy(app.Map,[LHor3_x' LHor3_y'], ones(length(LHor3_x),1))
                LHor3_Inity=LHor3_Inity+1/Resolution;
            end
            
            LHor4_Initx=19-4;
            LHor4_Inity=18.5;
            LHor4_Endx=19;
            LHor4_Points= (LHor4_Endx-LHor4_Initx)*Resolution;
            LHor4_x(1)=LHor4_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor4_y(1)=LHor4_Inity;
                for n=1:LHor4_Points
                    LHor4_x(n+1)=LHor4_x(n)+1/Resolution
                    LHor4_y(n+1)=LHor4_y(n);
                    
                end
                
                setOccupancy(app.Map,[LHor4_x' LHor4_y'], ones(length(LHor4_x),1))
                LHor4_Inity=LHor4_Inity+1/Resolution;
            end
            
            % Crear Marco Lineas Verticales
            LVer1_Inity=0;
            LVer1_Initx=0;
            LVer1_Endy=16;
            LVer1_Points= (LVer1_Endy-LVer1_Inity)*Resolution;
            LVer1_y(1)=LVer1_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer1_x(1)=LVer1_Initx;
                for n=1:LVer1_Points
                    LVer1_y(n+1)=LVer1_y(n)+1/Resolution
                    LVer1_x(n+1)=LVer1_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer1_x' LVer1_y'], ones(length(LVer1_y),1))
                LVer1_Initx=LVer1_Initx+1/Resolution;
            end
            
            LVer2_Inity=0;
            LVer2_Initx=3.5;
            LVer2_Endy=3.5;
            LVer2_Points= (LVer2_Endy-LVer2_Inity)*Resolution;
            LVer2_y(1)=LVer2_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer2_x(1)=LVer2_Initx;
                for n=1:LVer2_Points
                    LVer2_y(n+1)=LVer2_y(n)+1/Resolution
                    LVer2_x(n+1)=LVer2_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer2_x' LVer2_y'], ones(length(LVer2_y),1))
                LVer2_Initx=LVer2_Initx+1/Resolution;
            end
            
            LVer3_Inity=15.5;
            LVer3_Initx=15;
            LVer3_Endy=19;
            LVer3_Points= (LVer3_Endy-LVer3_Inity)*Resolution;
            LVer3_y(1)=LVer3_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer3_x(1)=LVer3_Initx;
                for n=1:LVer3_Points
                    LVer3_y(n+1)=LVer3_y(n)+1/Resolution
                    LVer3_x(n+1)=LVer3_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer3_x' LVer3_y'], ones(length(LVer3_y),1))
                LVer3_Initx=LVer3_Initx+1/Resolution;
            end
            
            LVer4_Inity=3;
            LVer4_Initx=18.5;
            LVer4_Endy=19;
            LVer4_Points= (LVer4_Endy-LVer4_Inity)*Resolution;
            LVer4_y(1)=LVer4_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer4_x(1)=LVer4_Initx;
                for n=1:LVer4_Points
                    LVer4_y(n+1)=LVer4_y(n)+1/Resolution
                    LVer4_x(n+1)=LVer4_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer4_x' LVer4_y'], ones(length(LVer4_y),1))
                LVer4_Initx=LVer4_Initx+1/Resolution;
            end
            
            %% Creación de Obstáculos No Convexos.
            LVer5_Inity=5.5;
            LVer5_Initx=3;
            LVer5_Endy=10.5;
            LVer5_Points= (LVer5_Endy-LVer5_Inity)*Resolution;
            LVer5_y(1)=LVer5_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer5_x(1)=LVer5_Initx;
                for n=1:LVer5_Points
                    LVer5_y(n+1)=LVer5_y(n)+1/Resolution
                    LVer5_x(n+1)=LVer5_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer5_x' LVer5_y'], ones(length(LVer5_y),1))
                LVer5_Initx=LVer5_Initx+1/Resolution;
            end
            
            LVer6_Inity=10.5;
            LVer6_Initx=11;
            LVer6_Endy=15.5;
            LVer6_Points= (LVer6_Endy-LVer6_Inity)*Resolution;
            LVer6_y(1)=LVer6_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer6_x(1)=LVer6_Initx;
                for n=1:LVer6_Points
                    LVer6_y(n+1)=LVer6_y(n)+1/Resolution;
                    LVer6_x(n+1)=LVer6_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer6_x' LVer6_y'], ones(length(LVer6_y),1))
                LVer6_Initx=LVer6_Initx+1/Resolution;
            end
            
            LVer7_Inity=5;
            LVer7_Initx=15.5;
            LVer7_Endy=8;
            LVer7_Points= (LVer7_Endy-LVer7_Inity)*Resolution;
            LVer7_y(1)=LVer7_Inity;
            
            for j=1:0.5/(1/Resolution)+1
                LVer7_x(1)=LVer7_Initx;
                for n=1:LVer7_Points
                    LVer7_y(n+1)=LVer7_y(n)+1/Resolution;
                    LVer7_x(n+1)=LVer7_x(n);
                    
                end
                
                setOccupancy(app.Map,[LVer7_x' LVer7_y'], ones(length(LVer7_y),1))
                LVer7_Initx=LVer7_Initx+1/Resolution;
            end
            
            LHor5_Initx=3;
            LHor5_Inity=10;
            LHor5_Endx=7;
            LHor5_Points= (LHor5_Endx-LHor5_Initx)*Resolution;
            LHor5_x(1)=LHor5_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor5_y(1)=LHor5_Inity;
                for n=1:LHor5_Points
                    LHor5_x(n+1)=LHor5_x(n)+1/Resolution;
                    LHor5_y(n+1)=LHor5_y(n);
                    
                end
                
                setOccupancy(app.Map,[LHor5_x' LHor5_y'], ones(length(LHor5_x),1))
                LHor5_Inity=LHor5_Inity+1/Resolution;
            end
            
            LHor6_Initx=10.5;
            LHor6_Inity=7.5;
            LHor6_Endx=15.5;
            LHor6_Points= (LHor6_Endx-LHor6_Initx)*Resolution;
            LHor6_x(1)=LHor6_Initx;
            
            for j=1:0.5/(1/Resolution)+1
                LHor6_y(1)=LHor6_Inity;
                for n=1:LHor6_Points
                    LHor6_x(n+1)=LHor6_x(n)+1/Resolution;
                    LHor6_y(n+1)=LHor6_y(n);
                    
                end
                
                setOccupancy(app.Map,[LHor6_x' LHor6_y'], ones(length(LHor6_x),1))
                LHor6_Inity=LHor6_Inity+1/Resolution;
            end
            show(app.Map,'parent',app.UIAxes)
            hold(app.UIAxes_3,"off")
            show(app.Map,'parent',app.UIAxes_3)
            hold(app.UIAxes_3,"on")
            inflate(app.Map,0.2)
            inflate(app.Map,0.4)
            app.Mapa.Color = [0, 1, 0];
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            app.StopNow = false;
            app.ParadaLamp.Enable = false;
            app.PausaLamp.Enable = false;
            app.OperacionLamp.Enable = true;
            %%
            %Se crea el publicador
            path2pub = rospublisher('/matlab_topic','std_msgs/Float32MultiArray');
            pathMsg = rosmessage(path2pub);
            pathMsg.Data = app.path;
            imagen = rossubscriber("/camera/rgb/image_raw");
            imagen.NewMessageFcn = @(~,msg) imagesc(reshape(msg.Data,[160,120])');
            odom = rossubscriber('/odom');
            x = app.xi.Value;
            y = app.yi.Value;
            plot(app.UIAxes_3,x,y)
            error_x = abs(x-app.xf.Value);
            error_y = abs(y-app.yf.Value);
            rho = sqrt(error_x^2+error_y^2);
            while  rho > 0.05 && ~app.StopNow
                send(path2pub,pathMsg);
                imagennueva = receive(imagen,1);
                imshow(imagennueva.readImage(),'parent',app.UIAxes_2);
                pose = receive(odom,2);
                pos = pose.Pose.Pose.Position;
                x = pos.X+2;
                y = pos.Y+2;
                quat = pose.Pose.Pose.Orientation;
                angles = quat2eul([quat.W quat.X quat.Y quat.Z]);
                yaw = angles(1);
                dibrob(app,x,y,0.04,yaw)
                yaw = rad2deg(yaw);
                error_x = abs(x-app.xf.Value);
                error_y = abs(y-app.yf.Value);
                rho = sqrt(error_x^2+error_y^2);
                app.x_robot.Text = num2str(round(x,2));
                app.y_robot.Text = num2str(round(y,2));
                app.yaw_robot.Text = num2str(round(yaw,2));
            end
            
            app.ParadaLamp.Enable = false;
            app.PausaLamp.Enable = true;
            app.OperacionLamp.Enable = false;
        end

        % Button pushed function: ParadaButton
        function ParadaButtonPushed(app, event)
            app.StopNow = true;
            app.PausaLamp.Enable = false;
            app.OperacionLamp.Enable = false;
            app.ParadaLamp.Enable = true;
        end

        % Value changed function: ConectaraROSSwitch
        function ConectaraROSSwitchValueChanged(app, event)
            value = app.ConectaraROSSwitch.Value;
            if value == "On"
                rosinit
            else
                app.StopNow = true;
                rosshutdown
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IndicadoresPanel = uipanel(app.UIFigure);
            app.IndicadoresPanel.Title = 'Indicadores';
            app.OperacionLampLabel = uilabel(app.IndicadoresPanel);
            app.OperacionLampLabel.Text = 'Operacion';
            app.OperacionLamp = uilamp(app.IndicadoresPanel);
            app.OperacionLamp.Enable = 'off';
            app.PausaLampLabel = uilabel(app.IndicadoresPanel);
            app.PausaLampLabel.Text = 'Pausa';
            app.PausaLamp = uilamp(app.IndicadoresPanel);
            app.ParadaLampLabel = uilabel(app.IndicadoresPanel);
            app.ParadaLampLabel.Text = 'Parada';
            app.ParadaLamp = uilamp(app.IndicadoresPanel);
            app.ParadaLamp.Enable = 'off';
            app.CreaciondelmapaPanel = uipanel(app.UIFigure);
            app.CreaciondelmapaPanel.Title = 'Creacion del mapa';
            app.ResolucionLabel = uilabel(app.CreaciondelmapaPanel);
            app.ResolucionLabel.Text = 'Resolucion:';
            app.Resolucion = uieditfield(app.CreaciondelmapaPanel, 'numeric');
            app.Resolucion.Value = 20;
            app.CargarmapaButton = uibutton(app.CreaciondelmapaPanel, 'push');
            app.CargarmapaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmapaButtonPushed, true);
            app.CargarmapaButton.Text = 'Cargar mapa';
            app.Mapa = uilamp(app.CreaciondelmapaPanel);
            app.UIAxes = uiaxes(app.CreaciondelmapaPanel);
            app.GeneracionderutaAlgoritmoPRMPanel = uipanel(app.UIFigure);
            app.GeneracionderutaAlgoritmoPRMPanel.Title = 'Generacion de ruta - Algoritmo PRM';
            app.PuntoinicialPanel = uipanel(app.GeneracionderutaAlgoritmoPRMPanel);
            app.PuntoinicialPanel.Title = 'Punto inicial';
            app.xLabel = uilabel(app.PuntoinicialPanel);
            app.xLabel.Text = 'x';
            app.xi = uieditfield(app.PuntoinicialPanel, 'numeric');
            app.xi.Value = 2;
            app.yLabel = uilabel(app.PuntoinicialPanel);
            app.yLabel.Text = 'y';
            app.yi = uieditfield(app.PuntoinicialPanel, 'numeric');
            app.yi.Value = 2;
            app.PuntofinalPanel = uipanel(app.GeneracionderutaAlgoritmoPRMPanel);
            app.PuntofinalPanel.Title = 'Punto final';
            app.xLabel_2 = uilabel(app.PuntofinalPanel);
            app.xLabel_2.Text = 'x';
            app.xf = uieditfield(app.PuntofinalPanel, 'numeric');
            app.xf.Value = 17;
            app.yLabel_2 = uilabel(app.PuntofinalPanel);
            app.yLabel_2.Text = 'y';
            app.yf = uieditfield(app.PuntofinalPanel, 'numeric');
            app.yf.Value = 17;
            app.NodosEditFieldLabel = uilabel(app.GeneracionderutaAlgoritmoPRMPanel);
            app.NodosEditFieldLabel.Text = 'Nodos';
            app.Nodos = uieditfield(app.GeneracionderutaAlgoritmoPRMPanel, 'numeric');
            app.Nodos.Limits = [0 1000];
            app.Nodos.RoundFractionalValues = 'on';
            app.Nodos.ValueDisplayFormat = '%11g';
            app.Nodos.Value = 250;
            app.GenerarRutaButton = uibutton(app.GeneracionderutaAlgoritmoPRMPanel, 'push');
            app.GenerarRutaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarRutaButtonPushed, true);
            app.GenerarRutaButton.Text = {'Generar '; 'Ruta'};
            app.Ruta = uilamp(app.GeneracionderutaAlgoritmoPRMPanel);
            app.ROSGazeboPanel = uipanel(app.UIFigure);
            app.ROSGazeboPanel.Title = 'ROS - Gazebo';
            app.ConectaraROSSwitch = uiswitch(app.ROSGazeboPanel, 'slider');
            app.ConectaraROSSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectaraROSSwitchValueChanged, true);
            app.InicioButton = uibutton(app.ROSGazeboPanel, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.ParadaButton = uibutton(app.ROSGazeboPanel, 'push');
            app.ParadaButton.ButtonPushedFcn = createCallbackFcn(app, @ParadaButtonPushed, true);
            app.ParadaButton.Text = 'Parada';
            app.ConectaraROSLabel = uilabel(app.ROSGazeboPanel);
            app.ConectaraROSLabel.Text = 'Conectar a ROS';
            app.RobotPanel = uipanel(app.ROSGazeboPanel);
            app.RobotPanel.Title = 'Robot';
            app.xLabel_3 = uilabel(app.RobotPanel);
            app.xLabel_3.Text = 'x =';
            app.yLabel_3 = uilabel(app.RobotPanel);
            app.yLabel_3.Text = 'y =';
            app.yawLabel = uilabel(app.RobotPanel);
            app.yawLabel.Text = 'yaw =';
            app.x_robot = uilabel(app.RobotPanel);
            app.x_robot.Text = '2';
            app.y_robot = uilabel(app.RobotPanel);
            app.y_robot.Text = '2';
            app.yaw_robot = uilabel(app.RobotPanel);
            app.yaw_robot.Text = '0';
            app.UIAxes_3 = uiaxes(app.ROSGazeboPanel);
            app.UIAxes_2 = uiaxes(app.ROSGazeboPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end